classdef Camera_Calibration_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraCalibration_GUI           matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalibrationFrames               matlab.ui.control.NumericEditField
        NumberofframestocalibrateLabel  matlab.ui.control.Label
        SelectedPointTextArea           matlab.ui.control.TextArea
        SelectedPointTextAreaLabel      matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        SelectthepositionofthefirstLabel  matlab.ui.control.Label
        CAMERACALIBRATIONLabel          matlab.ui.control.Label
        EII_Logo                        matlab.ui.control.Image
        UMA_Logo                        matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        Video                           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x, y] = selectDataPoints(~, ax)
    roi = drawpoint(ax);
    x = roi.Position(1);
    y = roi.Position(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % load("datos.mat")

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            GUI
            delete(app.CameraCalibration_GUI)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           load("datos.mat")

           N = app.CalibrationFrames.Value

            % get value field N
            if N<=3
                f = msgbox("The algorithm needs more than 3 frames (6 points) to do the calibration.");
                return
            end

            % Leemos el video seleccionado en la otra GUI

%              [file, path] = uigetfile('.mp4', 'Select Video File');
          
%              input_video_file = fullfile(path,file)

            
            v = VideoReader(input_video_file);
          %      while(hasFrame(vidObj))
             frame = readFrame(v);
             imshow(frame, 'Parent', app.UIAxes);   % <--- use the Parent property
                    %pause(2/vidObj.FrameRate);
              %  end
          %  end

% Guardamos los datos del excel en una tabla, solamente las columnas de
% interes

% columna 1: tiempo -> nueva columna 1
% columnas 82-84: posicion PSM1 (CD-CE-CF) -> nuevas columnas 2-4
% columnas 119-121: posicion PSM2 (DO-DP-DQ) -> nuevas columnas 5-7

opts = detectImportOptions(input_data_file);
opts.SelectedVariableNames = opts.SelectedVariableNames([1, 82, 83, 84, 119, 120, 121]); % seleccionamos las columnas de interes
SelectedTable = readtable(input_data_file,opts);
TableCell=table2cell(SelectedTable); %transformamos la matriz en tipo celda, más fácil para operar

% guardamos en la variable tiempo0, en formato datetime, el tiempo inicial del video

tiempo0 = TextToTime(1,TableCell);

% cogeremos 20 puntos para realizar la calibración. Para coger en
% intervalos iguales de la tabla, calculamos el número de líneas que
% contiene y la guardamos en 'ExtensionVideo' y lo dividimos por 10, siendo
% el último punto el último valor de la tabla (ya que, seguramente, el
% valor no sea un múltiple de 10)

[ExtensionVideo, aux]=size(TableCell);
div = fix(ExtensionVideo/N); %cogemos solo el valor entero de la división

%%div=v.Duration/10;
point=0;
for i = 1:N

    if i == N
        linea = ExtensionVideo-1; %penultima linea de la tabla ya que la ultima no tiene datos
    else
        linea = div*i;
    end
%     
%     TableCell(linea)
%     linea
    tiempo(i)=TextToTime(linea,TableCell);

    v.CurrentTime=second(tiempo(i),'secondofday')-second(tiempo0,'secondofday'); %calculamos cuanto tiempo, en segundos, se ha transcurrido de video
    frame=readFrame(v);

      imshow(frame, 'Parent', app.UIAxes); 
    % axes(handles.app.Video)
%     [x, y] = selectDataPoints(app.CameraCalibration_GUI, app.Video);
%     set(0, 'CurrentAxes', app.Video)       % Make fig current
%     [x,y]=ginput(2); % cogemos las coordenadas de las dos pinzas
%x = rand(10000,1);
%  y = rand(10000,1);
   %plot(app.UIAxes,x,y,'.');      
   x =[];y = [];
   for j = 1:1:2
    [x1, y1] = selectDataPoints(app, app.UIAxes);
    x(j) = x1;
    y(j) = y1;
    point=point+1;
    app.SelectedPointTextArea.Value = [strcat(string(point), ": (", string(x1), ", ", string(y1),")")];

   end
    
 
     imagePoints1(i,:)=[x(1),y(1)] %coordenadas de la pinza 1
     imagePoints2(i,:)=[x(2),y(2)] %coordenadas de la pinza 2
% 
%     % leemos del excel los puntos reales del robot PSM1, PSM2
% 
     worldPoints1(i,:)=[TableCell(linea,2), TableCell(linea,3), TableCell(linea,4)]; % cogemos de la tabla los valores mundo asociados a la pinza 1
     worldPoints2(i,:)=[TableCell(linea,5), TableCell(linea,6), TableCell(linea,7)]; % cogemos de la tabla los valores mundo asociados a la pinza 2

end

imagePoints = [imagePoints1;imagePoints2];
worldPoints = cell2mat([worldPoints1;worldPoints2]);

parametros = estimateCameraMatrix(imagePoints,worldPoints)
save("datos_calibracion.mat","parametros")
f = msgbox("Calibration completed!");

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraCalibration_GUI = uifigure('Visible', 'off');
            app.CameraCalibration_GUI.Name = 'Camera Calibration';
            app.Video = uiaxes(app.CameraCalibration_GUI);
            title(app.Video, 'Video')
            app.Video.XLim = [0 1];
            app.Video.XTick = [];
            app.Video.YTick = [];
            app.Video.ZTick = [];
            app.Video.Visible = 'off';
            app.UIAxes = uiaxes(app.CameraCalibration_GUI);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UMA_Logo = uiimage(app.CameraCalibration_GUI);
            app.UMA_Logo.ImageSource = 'icono uma.jpg';
            app.EII_Logo = uiimage(app.CameraCalibration_GUI);
            app.EII_Logo.ImageSource = 'icono eii.png';
            app.CAMERACALIBRATIONLabel = uilabel(app.CameraCalibration_GUI);
            app.CAMERACALIBRATIONLabel.Text = 'CAMERA CALIBRATION';
            app.SelectthepositionofthefirstLabel = uilabel(app.CameraCalibration_GUI);
            app.SelectthepositionofthefirstLabel.Text = {'After clicking on the Start button,'; 'Select the position of the first gripper (PSM1) and of the second gripper (PSM2), in that order'};
            app.BackButton = uibutton(app.CameraCalibration_GUI, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.StartButton = uibutton(app.CameraCalibration_GUI, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectedPointTextAreaLabel = uilabel(app.CameraCalibration_GUI);
            app.SelectedPointTextAreaLabel.Text = 'Selected Point:';
            app.SelectedPointTextArea = uitextarea(app.CameraCalibration_GUI);
            app.NumberofframestocalibrateLabel = uilabel(app.CameraCalibration_GUI);
            app.NumberofframestocalibrateLabel.Text = 'Number of frames to calibrate:';
            app.CalibrationFrames = uieditfield(app.CameraCalibration_GUI, 'numeric');
            app.CalibrationFrames.Limits = [0 Inf];
            app.Label = uilabel(app.CameraCalibration_GUI);
            app.Label.Text = 'Author: Guillermo Fernandez Lopes (guifdz2402@uma.es) - University of Malaga (Spain)';
            app.Label_2 = uilabel(app.CameraCalibration_GUI);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.CameraCalibration_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camera_Calibration_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CameraCalibration_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraCalibration_GUI)
        end
    end
end