classdef Creacion_database < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_app                   matlab.ui.Figure
        FrameEditField            matlab.ui.control.EditField
        FrameEditFieldLabel       matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        Indeterminada2            matlab.ui.control.Button
        Cerrada2                  matlab.ui.control.Button
        Abierta2                  matlab.ui.control.Button
        Indeterminada1            matlab.ui.control.Button
        Cerrada1                  matlab.ui.control.Button
        Abierta1                  matlab.ui.control.Button
        DAVINCIROBOTDATASETLabel  matlab.ui.control.Label
        EII_Logo                  matlab.ui.control.Image
        VideoFileLabel            matlab.ui.control.Label
        VideoFileField            matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        UMA_Logo                  matlab.ui.control.Image
        Video2                    matlab.ui.control.UIAxes
        Video1                    matlab.ui.control.UIAxes
        Video                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%         data = struct;
%         %filename;
%         %path;
         n;
%          next = true; 
         i; 
         j;
         im1;
         im2;
         datafile;
         videofile;
         parametros;
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              load("datos.mat")
%              [filepath, name ,ext] = fileparts(input_video_file); % Get the separate parts of the file
%              app.VideoFileField.Value = [name,'.mp4'];
%              input_data_file=[fullfile(filepath,name), '.csv'];
%              save("datos.mat","input_data_file",'-append');

              app.parametros=parametros;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [file, path] = uigetfile('.mp4', 'Select Video File');
            input_video_file = fullfile(path,file);
            [filepath, name ,~] = fileparts(input_video_file);
            input_data_file=[fullfile(filepath,name), '.csv'];
            app.VideoFileField.Value = file;

            app.datafile=input_data_file;
            app.videofile=input_video_file;

            app.i=560;
            app.j=1;

            
        end

        % Button pushed function: Abierta1
        function Abierta1ButtonPushed(app, event)

            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Abierta';
            imgName = [ImageFolder,'\Abierta_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im1,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: Cerrada1
        function Cerrada1ButtonPushed(app, event)
            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Cerrada';
            imgName = [ImageFolder,'\Cerrada_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im1,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: Indeterminada1
        function Indeterminada1ButtonPushed(app, event)
            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Indeterminada';
            imgName = [ImageFolder,'\Indeterminada_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im1,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: Abierta2
        function Abierta2ButtonPushed(app, event)
            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Abierta';
            imgName = [ImageFolder,'\Abierta_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im2,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: Cerrada2
        function Cerrada2ButtonPushed(app, event)
            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Cerrada';
            imgName = [ImageFolder,'\Cerrada_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im2,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: Indeterminada2
        function Indeterminada2ButtonPushed(app, event)
            ImageFolder ='C:\Users\guigu\Desktop\TFG\TFG_Guillermo\Matlab\New_Database\Indeterminada';
            imgName = [ImageFolder,'\Indeterminada_',num2str(app.j),'.png'] ;
            app.j=app.j+1;
            imwrite(app.im2,imgName) ; 
%             app.next=true;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            
            app.i = app.i+1;   

            app.FrameEditField.Value = [strcat(string(app.i), "/ ", string(app.n))];

            if(app.i == app.n)
                msgbox("Completado"); 
            end

            opts = detectImportOptions(app.datafile);
            opts.SelectedVariableNames = opts.SelectedVariableNames([1, 82, 83, 84, 119, 120, 121]); % seleccionamos las columnas de interes
            SelectedTable = readtable(app.datafile,opts);
            TableCell=table2cell(SelectedTable); %transformamos la matriz en tipo celda, más fácil para operar

%             app.on = true;

            [app.n, ~]=size(TableCell);

            v=VideoReader(app.videofile);

            tiempo0 = TextToTime(1,TableCell);


%while (app.i<app.n && app.on)


    % cogemos las coordenadas 3D de la  pinza 1 en la tabla
    X1 = cell2mat(TableCell(app.i,2)); 
    Y1 = cell2mat(TableCell(app.i,3));
    Z1 = cell2mat(TableCell(app.i,4));

    % cogemos las coordenadas 3D de la pinza 2 en la tabla
    X2 = cell2mat(TableCell(app.i,5)); 
    Y2 = cell2mat(TableCell(app.i,6));
    Z2 = cell2mat(TableCell(app.i,7));

    % sacamos las coordenadas 2D de la pinza 1 a partir de la matriz M

    mult1=[X1 Y1 Z1 1]*app.parametros;
    x1=mult1(1)/mult1(3); %componente x es la division entre el primer elemento y el tercero (k)
    y1=mult1(2)/mult1(3); %componente y es la division entre el primer elemento y el tercero (k)

    % sacamos las coordenadas 2D de la pinza 2 a partir de la matriz M

    mult2=[X2 Y2 Z2 1]*app.parametros;
    x2=mult2(1)/mult2(3); %componente x es la division entre el primer elemento y el tercero (k)
    y2=mult2(2)/mult2(3); %componente y es la division entre el primer elemento y el tercero (k)

    tiempoi=TextToTime(app.i,TableCell);

    v.CurrentTime=second(tiempoi,'secondofday')-second(tiempo0,'secondofday'); %calculamos cuanto tiempo, en segundos, se ha transcurrido de video
    frame=readFrame(v);
    
    % Representación de cada frame

    I = imshow(frame, 'Parent', app.Video, ...
        'XData', [1 app.Video.Position(3)], ...
        'YData', [1 app.Video.Position(4)]);

    % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video.XAxis.TickLabels = {};
        app.Video.YAxis.TickLabels = {};
        app.Video.XLim = [0 I.XData(2)];
        app.Video.YLim = [0 I.YData(2)];


    im1 = imcrop(frame,[x1-100,y1-100,200,200]);
    im2 = imcrop(frame,[x2-100,y2-100,200,200]);

    I1 = imshow(im1, 'Parent', app.Video1, ...
        'XData', [1 app.Video1.Position(3)], ...
        'YData', [1 app.Video1.Position(4)]);

        % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video1.XAxis.TickLabels = {};
        app.Video1.YAxis.TickLabels = {};
        app.Video1.XLim = [0 I1.XData(2)];
        app.Video1.YLim = [0 I1.YData(2)];

    I2 = imshow(im2, 'Parent', app.Video2, ...
        'XData', [1 app.Video2.Position(3)], ...
        'YData', [1 app.Video2.Position(4)]);

        % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video2.XAxis.TickLabels = {};
        app.Video2.YAxis.TickLabels = {};
        app.Video2.XLim = [0 I2.XData(2)];
        app.Video2.YLim = [0 I2.YData(2)];


%end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_app = uifigure('Visible', 'off');
            app.GUI_app.Name = 'GUI';
            app.Video = uiaxes(app.GUI_app);
            title(app.Video, 'Video')
            xlabel(app.Video, 'X')
            ylabel(app.Video, 'Y')
            zlabel(app.Video, 'Z')
            app.Video1 = uiaxes(app.GUI_app);
            title(app.Video1, 'PSM2')
            app.Video1.XTick = [0 0.5 1];
            app.Video2 = uiaxes(app.GUI_app);
            title(app.Video2, 'PSM1')
            app.Video2.XTick = [0 0.5 1];
            app.UMA_Logo = uiimage(app.GUI_app);
            app.UMA_Logo.ImageSource = 'icono uma.jpg';
            app.BrowseButton = uibutton(app.GUI_app, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse '; ''};
            app.VideoFileField = uieditfield(app.GUI_app, 'text');
            app.VideoFileLabel = uilabel(app.GUI_app);
            app.VideoFileLabel.Text = 'Video File:';
            app.EII_Logo = uiimage(app.GUI_app);
            app.EII_Logo.ImageSource = 'icono eii.png';
            app.DAVINCIROBOTDATASETLabel = uilabel(app.GUI_app);
            app.DAVINCIROBOTDATASETLabel.Text = 'DAVINCI ROBOT DATASET';
            app.Abierta1 = uibutton(app.GUI_app, 'push');
            app.Abierta1.ButtonPushedFcn = createCallbackFcn(app, @Abierta1ButtonPushed, true);
            app.Abierta1.Text = 'Abierta';
            app.Cerrada1 = uibutton(app.GUI_app, 'push');
            app.Cerrada1.ButtonPushedFcn = createCallbackFcn(app, @Cerrada1ButtonPushed, true);
            app.Cerrada1.Text = 'Cerrada';
            app.Indeterminada1 = uibutton(app.GUI_app, 'push');
            app.Indeterminada1.ButtonPushedFcn = createCallbackFcn(app, @Indeterminada1ButtonPushed, true);
            app.Indeterminada1.Text = 'Indeterminada';
            app.Abierta2 = uibutton(app.GUI_app, 'push');
            app.Abierta2.ButtonPushedFcn = createCallbackFcn(app, @Abierta2ButtonPushed, true);
            app.Abierta2.Text = 'Abierta';
            app.Cerrada2 = uibutton(app.GUI_app, 'push');
            app.Cerrada2.ButtonPushedFcn = createCallbackFcn(app, @Cerrada2ButtonPushed, true);
            app.Cerrada2.Text = 'Cerrada';
            app.Indeterminada2 = uibutton(app.GUI_app, 'push');
            app.Indeterminada2.ButtonPushedFcn = createCallbackFcn(app, @Indeterminada2ButtonPushed, true);
            app.Indeterminada2.Text = 'Indeterminada';
            app.NextButton = uibutton(app.GUI_app, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FrameEditFieldLabel = uilabel(app.GUI_app);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.GUI_app, 'text');
            % Show the figure after all components are created
            app.GUI_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Creacion_database

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_app)
        end
    end
end