classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_app                   matlab.ui.Figure
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        DAVINCIROBOTDATASETLabel  matlab.ui.control.Label
        CALIBRATEButton           matlab.ui.control.Button
        State1                    matlab.ui.control.EditField
        State2                    matlab.ui.control.EditField
        stop                      matlab.ui.control.Image
        pause                     matlab.ui.control.Image
        play                      matlab.ui.control.Image
        VideoFileLabel            matlab.ui.control.Label
        VideoFileField            matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        EII_Logo                  matlab.ui.control.Image
        UMA_Logo                  matlab.ui.control.Image
        TimesSlider               matlab.ui.control.Slider
        TimesSliderLabel          matlab.ui.control.Label
        CurrentFrame              matlab.ui.control.EditField
        Video                     matlab.ui.control.UIAxes
        Video2                    matlab.ui.control.UIAxes
        Video1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n;
        on = true; 
        i = 1; 
        datafile;
        videofile;
        parametros;
        net;
        v;
        Classified1;
        Classified2;
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             load("datos.mat")

              app.parametros=parametros;
              app.net=trainedNet;
        end

        % Button pushed function: CALIBRATEButton
        function CALIBRATEButtonPushed(app, event)

            if strcmp(app.VideoFileField.Value,'')==1 %si no hay vido seleccionado salta una alerta
                msgbox("Select a video file to calibrate");                
            else
                delete(app.GUI_app) % cerramos la ventana actual
                Camera_Calibration_GUI  %abrimos GUI de calibracion
            end
           
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Obtención del archivo de video a partir de un fichero
            [file, path] = uigetfile('.mp4', 'Select Video File');

            % A partir del fichero de video, obtenemos el fichero de datos que contiene el mismo nombre
            input_video_file = fullfile(path,file);
            [filepath, name ,~] = fileparts(input_video_file);
            input_data_file=[fullfile(filepath,name), '.csv'];

            % Guardamos las variables en el un fichero matlab
            save("datos.mat",'input_video_file', '-append');
            save("datos.mat","input_data_file",'-append');
            app.VideoFileField.Value = file;

            app.datafile = input_data_file;
            app.videofile = input_video_file;

            app.v = VideoReader(input_video_file);            

            app.i=1;
            app.n = app.v.NumFrames;

            % Definimos es slider de tiempo

            app.TimesSlider.Limits = [0 app.n];
            app.TimesSlider.MajorTicks = [0 app.n];
            app.TimesSlider.MajorTickLabels = {'1',num2str(app.n)};
            
            
        end

        % Image clicked function: play
        function playImageClicked(app, event)
            
            % Guardamos los datos del excel en una tabla, solamente las columnas de interes

            % columna 1: tiempo -> nueva columna 1
            % columnas 82-84: posicion PSM1 (CD-CE-CF) -> nuevas columnas 2-4
            % columnas 119-121: posicion PSM2 (DO-DP-DQ) -> nuevas columnas 5-7

            opts = detectImportOptions(app.datafile);
            opts.SelectedVariableNames = opts.SelectedVariableNames([1, 82, 83, 84, 119, 120, 121]); % seleccionamos las columnas de interes
            SelectedTable = readtable(app.datafile,opts);
            TableCell=table2cell(SelectedTable); % transformamos la matriz en tipo celda, más fácil para operar
          
            app.on = true;
            

while (app.i<app.n && app.on && (app.i*2-1)<length(TableCell))


    app.v.CurrentTime=(app.i-1)*0.04; % calculamos el tiempo pasado, ya que cada frame tiene 40ms
    l = app.i*2-1; % como los datos se captaron con el doble de frecuencia (cada 20ms), para cada frame saltamos un dato de la tabla. Accedemos a la linea l de la tabla
    % tiempo = v.CurrentTime;
    % tiempol=TextToTime(l,TableCell);
    % current = second(tiempol,'secondofday')-second(tiempo0,'secondofday');

    app.TimesSlider.Value = app.i; 
    app.CurrentFrame.Value = num2str(app.i); % mostramos el número actual del frame

    % cogemos las coordenadas 3D de la  pinza 1 en la tabla
    X1 = cell2mat(TableCell(l,2)); 
    Y1 = cell2mat(TableCell(l,3));
    Z1 = cell2mat(TableCell(l,4));

    % cogemos las coordenadas 3D de la pinza 2 en la tabla
    X2 = cell2mat(TableCell(l,5)); 
    Y2 = cell2mat(TableCell(l,6));
    Z2 = cell2mat(TableCell(l,7));

    % sacamos las coordenadas 2D de la pinza 1 a partir de la matriz M

    mult1=[X1 Y1 Z1 1]*app.parametros;
    x1=mult1(1)/mult1(3); %componente x es la division entre el primer elemento y el tercero (k)
    y1=mult1(2)/mult1(3); %componente y es la division entre el primer elemento y el tercero (k)

    % sacamos las coordenadas 2D de la pinza 2 a partir de la matriz M

    mult2=[X2 Y2 Z2 1]*app.parametros;
    x2=mult2(1)/mult2(3); %componente x es la division entre el primer elemento y el tercero (k)
    y2=mult2(2)/mult2(3); %componente y es la division entre el primer elemento y el tercero (k)

    
    frame=readFrame(app.v);
    
    % Representación de cada frame

    I = imshow(frame, 'Parent', app.Video, ...
        'XData', [1 app.Video.Position(3)], ...
        'YData', [1 app.Video.Position(4)]);

    % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video.XAxis.TickLabels = {};
        app.Video.YAxis.TickLabels = {};
        app.Video.XLim = [0 I.XData(2)];
        app.Video.YLim = [0 I.YData(2)];
%      hold(app.Video,'on');
%      plot(app.Video,x1,y1,'c+:')
%      plot(app.Video,x2,y2,'r+:')

    im1 = imcrop(frame,[x1-100,y1-100,200,200]);
    im2 = imcrop(frame,[x2-100,y2-100,200,200]);

    I1 = imshow(im1, 'Parent', app.Video1, ...
        'XData', [1 app.Video1.Position(3)], ...
        'YData', [1 app.Video1.Position(4)]);

        % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video1.XAxis.TickLabels = {};
        app.Video1.YAxis.TickLabels = {};
        app.Video1.XLim = [0 I1.XData(2)];
        app.Video1.YLim = [0 I1.YData(2)];

    I2 = imshow(im2, 'Parent', app.Video2, ...
        'XData', [1 app.Video2.Position(3)], ...
        'YData', [1 app.Video2.Position(4)]);

        % Configuraciones para que la imagen ocupe todo el espacio del axes
        app.Video2.XAxis.TickLabels = {};
        app.Video2.YAxis.TickLabels = {};
        app.Video2.XLim = [0 I2.XData(2)];
        app.Video2.YLim = [0 I2.YData(2)];

    % Clasificamos cada imagen y representamos el estado de la pinza en el
    % recuadro de texto 
    
    % Clasificamos subimagen 1

    if(isempty(im1)==0)
        app.Classified1 = ClassifyImg(app.net,im1,l,app.Classified1);
        
        if (app.Classified1(l,1)==1)
            app.State1.Value = "Closed";
        elseif (app.Classified1(l,1)==2)
            app.State1.Value = "Opened";
        elseif (app.Classified1(l,1)==3) % Si la pinza está en estado indeterminado, es muy posible que tenga el mismo estado identificado anteriormente
            if (l>1 && app.Classified1((l-2),1)==1)
                app.State1.Value = "Unknown (Closed)";
                app.Classified1(l,1)=1;
            elseif (l>1 && app.Classified1((l-2),1)==2)
                app.State1.Value = "Unknown (Opened)";
                app.Classified1(l,1)=2;
            else
                app.State1.Value = "Unknown";
            end
        end
    else
        app.Classified1(l,1)=3;
    end
    
    % Clasificamos subimagen 2

    if(isempty(im2)==0)
        app.Classified2 = ClassifyImg(app.net,im2,l,app.Classified2);
            
        if (app.Classified2(l,1)==1)
            app.State2.Value = "Closed";
        elseif (app.Classified2(l,1)==2)
            app.State2.Value = "Opened";
        elseif (app.Classified2(l,1)==3)
            if (l>1 && app.Classified2((l-2),1)==1)
                app.State2.Value = "Unknown (Closed)";
                app.Classified2(l,1)=1;
            elseif (l>1 && app.Classified2((l-2),1)==2)
                app.State2.Value = "Unknown (Opened)";
                app.Classified2(l,1)=2;
            else
                app.State2.Value = "Unknown";
            end
        end
    else
        app.Classified2(l,1)=3;
    end

%     if (app.Classified1(l,1)==1)
%         app.State1.Value = "Closed";
%     elseif (app.Classified1(l,1)==2)
%         app.State1.Value = "Opened";
%     elseif (app.Classified1(l,1)==3)
%         app.State1.Value = "Unknown";
%     end
%     
%     if (app.Classified2(l,1)==1)
%         app.State2.Value = "Closed";
%     elseif (app.Classified2(l,1)==2)
%         app.State2.Value = "Opened";
%     elseif (app.Classified2(l,1)==3)
%         app.State2.Value = "Unknown";
%     end

    app.i = app.i+1;

end

if l==length(TableCell)

    row1=app.Classified1;
    row2=app.Classified2;
    
    % Añadimos a la tabla las dos últimas columnas de datos
    
    ExistingData = readtable(app.datafile);
    NewData = AddRowsToData(ExistingData,row1,row2);
    
    % Reescribimos los datos del archivo csv con el correcto etiquetado
    
    writetable(NewData,app.datafile); 
end


        end

        % Image clicked function: pause
        function pauseImageClicked(app, event)
            app.on = false;
        end

        % Image clicked function: stop
        function stopImageClicked(app, event)
            app.on = false;
            app.i = 1;

            app.TimesSlider.Value = app.i;
            app.CurrentFrame.Value = num2str(app.i);

            % Enseñamos el primer frame

            app.v=VideoReader(app.videofile);
            app.v.CurrentTime=0;
            frame=readFrame(app.v);
    
           % Representación del primer frame

            I = imshow(frame, 'Parent', app.Video, ...
                'XData', [1 app.Video.Position(3)], ...
                'YData', [1 app.Video.Position(4)]);
        
            % Configuraciones para que la imagen ocupe todo el espacio del axes
                app.Video.XAxis.TickLabels = {};
                app.Video.YAxis.TickLabels = {};
                app.Video.XLim = [0 I.XData(2)];
                app.Video.YLim = [0 I.YData(2)];

            cla(app.Video1);
            cla(app.Video2);
            cla(app.State1);
            cla(app.State2);

        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.i = round(value);
            app.CurrentFrame.Value = num2str(app.i);
            app.v.CurrentTime=(app.i-1)*0.04;
            l = app.i*2-1;

            if app.on == false % Si no se está ejecutando el video, representamos el frame seleccionado y los recortes

                    opts = detectImportOptions(app.datafile);
                    opts.SelectedVariableNames = opts.SelectedVariableNames([1, 82, 83, 84, 119, 120, 121]); % seleccionamos las columnas de interes
                    SelectedTable = readtable(app.datafile,opts);
                    TableCell=table2cell(SelectedTable); % transformamos la matriz en tipo celda, más fácil para operar

                     % cogemos las coordenadas 3D de la  pinza 1 en la tabla
                    X1 = cell2mat(TableCell(l,2)); 
                    Y1 = cell2mat(TableCell(l,3));
                    Z1 = cell2mat(TableCell(l,4));
                
                    % cogemos las coordenadas 3D de la pinza 2 en la tabla
                    X2 = cell2mat(TableCell(l,5)); 
                    Y2 = cell2mat(TableCell(l,6));
                    Z2 = cell2mat(TableCell(l,7));
                
                    % sacamos las coordenadas 2D de la pinza 1 a partir de la matriz M
                
                    mult1=[X1 Y1 Z1 1]*app.parametros;
                    x1=mult1(1)/mult1(3); %componente x es la division entre el primer elemento y el tercero (k)
                    y1=mult1(2)/mult1(3); %componente y es la division entre el primer elemento y el tercero (k)
                
                    % sacamos las coordenadas 2D de la pinza 2 a partir de la matriz M
                
                    mult2=[X2 Y2 Z2 1]*app.parametros;
                    x2=mult2(1)/mult2(3); %componente x es la division entre el primer elemento y el tercero (k)
                    y2=mult2(2)/mult2(3); %componente y es la division entre el primer elemento y el tercero (k)
                                
                    frame=readFrame(app.v);
    
                    % Representación de cada imagen
                
                    I = imshow(frame, 'Parent', app.Video, ...
                        'XData', [1 app.Video.Position(3)], ...
                        'YData', [1 app.Video.Position(4)]);
                                    
                    % Configuraciones para que la imagen ocupe todo el espacio del axis
                        app.Video.XAxis.TickLabels = {};
                        app.Video.YAxis.TickLabels = {};
                        app.Video.XLim = [0 I.XData(2)];
                        app.Video.YLim = [0 I.YData(2)];

                
                    im1 = imcrop(frame,[x1-100,y1-100,200,200]);
                    im2 = imcrop(frame,[x2-100,y2-100,200,200]);

                    I1 = imshow(im1, 'Parent', app.Video1, ...
                        'XData', [1 app.Video1.Position(3)], ...
                        'YData', [1 app.Video1.Position(4)]);
                
                        % Configuraciones para que la imagen ocupe todo el espacio del axis
                        app.Video1.XAxis.TickLabels = {};
                        app.Video1.YAxis.TickLabels = {};
                        app.Video1.XLim = [0 I1.XData(2)];
                        app.Video1.YLim = [0 I1.YData(2)];
                
                    I2 = imshow(im2, 'Parent', app.Video2, ...
                        'XData', [1 app.Video2.Position(3)], ...
                        'YData', [1 app.Video2.Position(4)]);
                
                        % Configuraciones para que la imagen ocupe todo el espacio del axis
                        app.Video2.XAxis.TickLabels = {};
                        app.Video2.YAxis.TickLabels = {};
                        app.Video2.XLim = [0 I2.XData(2)];
                        app.Video2.YLim = [0 I2.YData(2)];

                    % Clasificamos cada imagen y representamos el estado de la pinza en el
                    % recuadro de texto 
                    
                    % Clasificamos subimagen 1

    if(isempty(im1)==0)
        app.Classified1 = ClassifyImg(app.net,im1,l,app.Classified1);
        
        if (app.Classified1(l,1)==1)
            app.State1.Value = "Closed";
        elseif (app.Classified1(l,1)==2)
            app.State1.Value = "Opened";
        elseif (app.Classified1(l,1)==3) % Si la pinza está en estado indeterminado, es muy posible que tenga el mismo estado identificado anteriormente
            if (l>1 && app.Classified1((l-2),1)==1)
                app.State1.Value = "Unknown (Closed)";
                app.Classified2(l,1)=1;
            elseif (l>1 && app.Classified1((l-2),1)==2)
                app.State1.Value = "Unknown (Opened)";
                app.Classified2(l,1)=2;
            else
                app.State1.Value = "Unknown";
            end
        end
    else
        app.Classified1(l,1)=3;
    end
    
    % Clasificamos subimagen 2

    if(isempty(im2)==0)
        app.Classified2 = ClassifyImg(app.net,im2,l,app.Classified2);
            
        if (app.Classified2(l,1)==1)
            app.State2.Value = "Closed";
        elseif (app.Classified2(l,1)==2)
            app.State2.Value = "Opened";
        elseif (app.Classified2(l,1)==3)
            if (l>1 && app.Classified2((l-2),1)==1)
                app.State2.Value = "Unknown (Closed)";
                app.Classified2(l,1)=1;
            elseif (l>1 && app.Classified2((l-2),1)==2)
                app.State2.Value = "Unknown (Opened)";
                app.Classified2(l,1)=2;
            else
                app.State2.Value = "Unknown";
            end
        end
    else
        app.Classified2(l,1)=3;
    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_app = uifigure('Visible', 'off');
            app.GUI_app.Name = 'GUI';
            app.Video1 = uiaxes(app.GUI_app);
            title(app.Video1, 'PSM2')
            app.Video1.XTick = [0 0.5 1];
            app.Video2 = uiaxes(app.GUI_app);
            title(app.Video2, 'PSM1')
            app.Video2.XTick = [0 0.5 1];
            app.Video = uiaxes(app.GUI_app);
            title(app.Video, 'Video')
            xlabel(app.Video, 'X')
            ylabel(app.Video, 'Y')
            zlabel(app.Video, 'Z')
            app.CurrentFrame = uieditfield(app.GUI_app, 'text');
            app.CurrentFrame.Enable = 'off';
            app.TimesSliderLabel = uilabel(app.GUI_app);
            app.TimesSliderLabel.Text = 'Frame';
            app.TimesSlider = uislider(app.GUI_app);
            app.TimesSlider.MajorTicks = [];
            app.TimesSlider.MajorTickLabels = {''};
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.MinorTicks = [];
            app.UMA_Logo = uiimage(app.GUI_app);
            app.UMA_Logo.ImageSource = 'icono uma.jpg';
            app.EII_Logo = uiimage(app.GUI_app);
            app.EII_Logo.ImageSource = 'icono eii.png';
            app.BrowseButton = uibutton(app.GUI_app, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse '; ''};
            app.VideoFileField = uieditfield(app.GUI_app, 'text');
            app.VideoFileLabel = uilabel(app.GUI_app);
            app.VideoFileLabel.Text = 'Video File:';
            app.play = uiimage(app.GUI_app);
            app.play.ImageClickedFcn = createCallbackFcn(app, @playImageClicked, true);
            app.play.ImageSource = 'play.png';
            app.pause = uiimage(app.GUI_app);
            app.pause.ImageClickedFcn = createCallbackFcn(app, @pauseImageClicked, true);
            app.pause.ImageSource = 'pause.png';
            app.stop = uiimage(app.GUI_app);
            app.stop.ImageClickedFcn = createCallbackFcn(app, @stopImageClicked, true);
            app.stop.ImageSource = 'stop.png';
            app.State2 = uieditfield(app.GUI_app, 'text');
            app.State2.Enable = 'off';
            app.State1 = uieditfield(app.GUI_app, 'text');
            app.State1.Enable = 'off';
            app.CALIBRATEButton = uibutton(app.GUI_app, 'push');
            app.CALIBRATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATEButtonPushed, true);
            app.CALIBRATEButton.Text = 'CALIBRATE';
            app.DAVINCIROBOTDATASETLabel = uilabel(app.GUI_app);
            app.DAVINCIROBOTDATASETLabel.Text = 'DAVINCI ROBOT DATASET';
            app.Label = uilabel(app.GUI_app);
            app.Label.Text = 'Author: Guillermo Fernandez Lopes (guifdz2402@uma.es) - University of Malaga (Spain)';
            app.Label_2 = uilabel(app.GUI_app);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.GUI_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_app)
        end
    end
end